classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        UploadzoneTab            matlab.ui.container.Tab
        MenuLabel                matlab.ui.control.Label
        CalculateestimatedmicrocirculationButton  matlab.ui.control.Button
        LoadpostinterventionimageButton  matlab.ui.control.Button
        LoadbaselineimageButton  matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        postAxes                 matlab.ui.control.UIAxes
        preAxes                  matlab.ui.control.UIAxes
        CalculationzoneTab       matlab.ui.container.Tab
        Image                    matlab.ui.control.Image
        Label                    matlab.ui.control.Label
        IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadbaselineimageButton
        function LoadbaselineimageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           if(ImageFile)
               disp("LALALALALALALA");
           end
           imshow(ImageFile, 'Parent' , app.preAxes);
        end

        % Button pushed function: LoadpostinterventionimageButton
        function LoadpostinterventionimageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imshow(ImageFile, 'Parent' , app.postAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel = uilabel(app.UIFigure);
            app.IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel.Text = 'Intramuscular flow Assessment with Esaote MicroV Measurements';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ua_aesthetic.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadzoneTab = uitab(app.TabGroup);
            app.UploadzoneTab.Title = 'Upload zone';
            app.preAxes = uiaxes(app.UploadzoneTab);
            zlabel(app.preAxes, 'Z')
            app.preAxes.XTickLabel = '';
            app.preAxes.YTickLabel = '';
            app.postAxes = uiaxes(app.UploadzoneTab);
            zlabel(app.postAxes, 'Z')
            app.postAxes.XTickLabel = '';
            app.postAxes.YTickLabel = '';
            app.Label_2 = uilabel(app.UploadzoneTab);
            app.Label_2.Text = '';
            app.LoadbaselineimageButton = uibutton(app.UploadzoneTab, 'push');
            app.LoadbaselineimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadbaselineimageButtonPushed, true);
            app.LoadbaselineimageButton.Text = 'Load baseline image';
            app.LoadpostinterventionimageButton = uibutton(app.UploadzoneTab, 'push');
            app.LoadpostinterventionimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpostinterventionimageButtonPushed, true);
            app.LoadpostinterventionimageButton.Text = 'Load post intervention image';
            app.CalculateestimatedmicrocirculationButton = uibutton(app.UploadzoneTab, 'push');
            app.CalculateestimatedmicrocirculationButton.Enable = 'off';
            app.CalculateestimatedmicrocirculationButton.Text = 'Calculate estimated microcirculation';
            app.MenuLabel = uilabel(app.UploadzoneTab);
            app.MenuLabel.Text = 'Menu';
            app.CalculationzoneTab = uitab(app.TabGroup);
            app.CalculationzoneTab.Title = 'Calculation zone';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end